classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MitometerUIFigure              matlab.ui.Figure
        DebugButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        Start2DButton                  matlab.ui.control.Button
        Start3DButton                  matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        MitometerLabel                 matlab.ui.control.Label
        SegmentationTab                matlab.ui.container.Tab
        segFigOG                       matlab.ui.control.UIAxes
        segFigMed                      matlab.ui.control.UIAxes
        segFigObjects                  matlab.ui.control.UIAxes
        segFigFinal                    matlab.ui.control.UIAxes
        OriginalImageLabel             matlab.ui.control.Label
        DiffuseBackgroundRemovedLabel  matlab.ui.control.Label
        ObjectsRandomlyLabeled         matlab.ui.control.Label
        FinalImageLabel                matlab.ui.control.Label
        SelectedImageButtonGroup       matlab.ui.container.ButtonGroup
        OriginalButton                 matlab.ui.control.ToggleButton
        DiffuseButton                  matlab.ui.control.ToggleButton
        ObjectsButton                  matlab.ui.control.ToggleButton
        FinalButton                    matlab.ui.control.ToggleButton
        FrameSliderLabel               matlab.ui.control.Label
        FrameSlider                    matlab.ui.control.Slider
        Spinner                        matlab.ui.control.Spinner
        PlaySeg                        matlab.ui.control.Button
        SavetifstackButton             matlab.ui.control.Button
        Spinner_2                      matlab.ui.control.Spinner
        ZPlaneSliderLabel              matlab.ui.control.Label
        ZPlaneSlider                   matlab.ui.control.Slider
        TrackingTab                    matlab.ui.container.Tab
        TrackFig                       matlab.ui.control.UIAxes
        TrackViewLabel                 matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        TracklengththresholdLabel      matlab.ui.control.Label
        keptTracksThresh               matlab.ui.control.Spinner
        TracktoviewLabel               matlab.ui.control.Label
        singleTrack                    matlab.ui.control.Spinner
        ObjectCheckBox                 matlab.ui.control.CheckBox
        SavetrackstomatfilesPanel      matlab.ui.container.Panel
        SelectedAll                    matlab.ui.control.Button
        SelectedKept                   matlab.ui.control.Button
        SelectedPerf                   matlab.ui.control.Button
        AllPerf                        matlab.ui.control.Button
        AllKept                        matlab.ui.control.Button
        AllAll                         matlab.ui.control.Button
        AlltracksLabel                 matlab.ui.control.Label
        KepttracksLabel                matlab.ui.control.Label
        PerfecttracksLabel             matlab.ui.control.Label
        ZAspectRatioSpinnerLabel       matlab.ui.control.Label
        ZAspectRatioSpinner            matlab.ui.control.Spinner
        SelectedButton                 matlab.ui.control.Button
        AllButton                      matlab.ui.control.Button
        SavetracksLabel                matlab.ui.control.Label
        AnalysisTab                    matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        ListBox                        matlab.ui.control.ListBox
        CreategroupButton              matlab.ui.control.Button
        GroupsDropDownLabel            matlab.ui.control.Label
        GroupsDropDown                 matlab.ui.control.DropDown
        AddtographButton               matlab.ui.control.Button
        SavetotxtButton                matlab.ui.control.Button
        LogscaleCheckBox               matlab.ui.control.CheckBox
        ResetgraphButton               matlab.ui.control.Button
        GraphtypeButtonGroup           matlab.ui.container.ButtonGroup
        HistogramButton                matlab.ui.control.RadioButton
        MedianBarButton                matlab.ui.control.RadioButton
        pvalueEditFieldLabel           matlab.ui.control.Label
        pvalueEditField                matlab.ui.control.NumericEditField
        OnewayANOVALabel               matlab.ui.control.Label
        AdvancedSettingsTab            matlab.ui.container.Tab
        SegmentationPanel              matlab.ui.container.Panel
        MinimummitochondrialareaumEditFieldLabel  matlab.ui.control.Label
        customMinArea                  matlab.ui.control.NumericEditField
        MaximummitochondrialareaumEditFieldLabel  matlab.ui.control.Label
        customMaxArea                  matlab.ui.control.NumericEditField
        CustomgaussfiltsigmaEditFieldLabel  matlab.ui.control.Label
        customSigVal                   matlab.ui.control.NumericEditField
        gaussCheckbox                  matlab.ui.control.CheckBox
        CustomthresholdEditFieldLabel  matlab.ui.control.Label
        customThrVal                   matlab.ui.control.NumericEditField
        threshCheckbox                 matlab.ui.control.CheckBox
        TrackingPanel                  matlab.ui.container.Panel
        MaxvelocitythresholdmsEditFieldLabel  matlab.ui.control.Label
        customDistThresh               matlab.ui.control.NumericEditField
        MaxwaittimethresholdsEditFieldLabel  matlab.ui.control.Label
        customFrameThresh              matlab.ui.control.NumericEditField
        fisFusCheckbox                 matlab.ui.control.CheckBox
        CustomweightsLabel             matlab.ui.control.Label
        DistanceEditFieldLabel         matlab.ui.control.Label
        customDistWeight               matlab.ui.control.NumericEditField
        AreaVolumeEditFieldLabel       matlab.ui.control.Label
        customAreaWeight               matlab.ui.control.NumericEditField
        MajorAxisEditFieldLabel        matlab.ui.control.Label
        customMajAxWeight              matlab.ui.control.NumericEditField
        MinorAxisEditFieldLabel        matlab.ui.control.Label
        customMinAxWeight              matlab.ui.control.NumericEditField
        SolidityEditFieldLabel         matlab.ui.control.Label
        customSolWeight                matlab.ui.control.NumericEditField
        PerimeterSurfaceAreaEditFieldLabel  matlab.ui.control.Label
        customPeriWeight               matlab.ui.control.NumericEditField
        IntensityEditFieldLabel        matlab.ui.control.Label
        customIntWeight                matlab.ui.control.NumericEditField
        ZAxis3DonlyEditFieldLabel      matlab.ui.control.Label
        customZAxWeight                matlab.ui.control.NumericEditField
        customWeightCheckbox           matlab.ui.control.CheckBox
        LoadingLabel                   matlab.ui.control.Label
        ImageList                      matlab.ui.control.ListBox
        SelectfilesLabel               matlab.ui.control.Label
        SelecthighlightedButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        ImAll
        fileName
        path % Description
        micronPerPixel
        secondsPerFrame % Description
        totalNumIms % Description
        zPlane
        zDistance % Description
        minArea = 0.3;
        maxArea = 200;
        distThreshMicrons = 1;
        frameThreshSeconds = -1;% Description
        sig
        thr % Description
        fisFusSkip % Description
        weights % Description
        holdAll % Description
        segFigHold % Description
        figNum = 1;% Description
        playOn = 0; % Description
        selectedIm = 1; % Description
        keptTracks % Description
        trackTypeHold % Description
        outlineOn % Description
        listAll = {} % Description
        allSelected % Description
        lengthThresh % Description
        edgeMito
        centerMito % Description
        callGroupingApp % Description
        trackTypeGrouping = "1";
        lengthThreshGrouping = 1; % Description
        groupList = {}; % Description
        groupListName = {}; % Description
        allSelectedParams % Description
        selectedGroup % Description
        groupDyn % Description
        logOn % Description
        graphGroups = {}; % Description
        groupMedVals = {}; % Description
        is3D = false; % Description
        zPlaneSelected=1; % Description
        zRatio % Description
        zAspectRatio = 1;% Description
        trackSave = 1; % Description
    end
    
    methods (Access = private)
        
        function updateSegFigs(app,imNum,frameNum)
            app.LoadingLabel.Visible = true;
            pause(0.02)
            
            app.segFigHold = app.holdAll(imNum);
            segFig = app.segFigHold;
            
            if app.is3D
                labelIm = label2rgb(segFig.extra.L{frameNum}(:,:,app.zPlaneSelected),'hsv','k','shuffle');
                imagesc(app.segFigOG,segFig.ImOG(:,:,frameNum,app.zPlaneSelected))
                imagesc(app.segFigMed,segFig.ImBgRemoved(:,:,frameNum,app.zPlaneSelected))
                imagesc(app.segFigObjects,labelIm)
                imagesc(app.segFigFinal,segFig.Im(:,:,frameNum,app.zPlaneSelected))
            else
                labelIm = label2rgb(segFig.extra.L{frameNum},'hsv','k','shuffle');
                imagesc(app.segFigOG,segFig.ImOG(:,:,frameNum))
                imagesc(app.segFigMed,segFig.ImBgRemoved(:,:,frameNum))
                imagesc(app.segFigObjects,labelIm)
                imagesc(app.segFigFinal,segFig.Im(:,:,frameNum))
            end


            app.segFigOG.XLim = [0,size(segFig.ImOG,2)];
            app.segFigOG.YLim = [0,size(segFig.ImOG,1)];
            app.segFigMed.XLim = [0,size(segFig.ImOG,2)];
            app.segFigMed.YLim = [0,size(segFig.ImOG,1)];
            app.segFigObjects.XLim = [0,size(segFig.ImOG,2)];
            app.segFigObjects.YLim = [0,size(segFig.ImOG,1)];
            app.segFigFinal.XLim = [0,size(segFig.ImOG,2)];
            app.segFigFinal.YLim = [0,size(segFig.ImOG,1)];
            
            app.Spinner.Limits = [1,size(segFig.ImOG,3)];
            app.FrameSlider.Limits = [1,size(segFig.ImOG,3)];
            if app.is3D
                app.Spinner_2.Limits = [1,size(segFig.ImOG,4)];
                app.ZPlaneSlider.Enable = true;
                app.Spinner_2.Enable = true;
%                 app.
                app.ZPlaneSlider.Limits = [1,size(segFig.ImOG,4)];
            end
            
            app.LoadingLabel.Visible = false;
            
        end
        
        function updateSegFigFrame(app,frameNum,figNum)
            segFig = app.segFigHold;
            if figNum == 1
                if app.is3D
                    imagesc(app.segFigOG,segFig.ImOG(:,:,frameNum,app.zPlaneSelected))
                else
                    imagesc(app.segFigOG,segFig.ImOG(:,:,frameNum))
                end
            elseif figNum == 2
                if app.is3D
                    imagesc(app.segFigMed,segFig.ImBgRemoved(:,:,frameNum,app.zPlaneSelected))
                else
                    imagesc(app.segFigMed,segFig.ImBgRemoved(:,:,frameNum))
                end
            elseif figNum == 3
                if app.is3D
                    labelIm = label2rgb(segFig.extra.L{frameNum}(:,:,app.zPlaneSelected),'hsv','k','shuffle');
                    imagesc(app.segFigObjects,labelIm)
                else
                    labelIm = label2rgb(segFig.extra.L{frameNum},'hsv','k','shuffle');
                    imagesc(app.segFigObjects,labelIm)
                end
            elseif figNum == 4
                if app.is3D
                    imagesc(app.segFigFinal,segFig.Im(:,:,frameNum,app.zPlaneSelected))
                else
                    imagesc(app.segFigFinal,segFig.Im(:,:,frameNum))
                end
            end
        end
        
        function updateTrackFig(app,trackType,figNum)
            app.LoadingLabel.Visible = true;
            pause(0.02)

            app.keptTracksThresh.Enable = true;
            
            lenThresh = app.keptTracksThresh.Value;
            noTracksFound = 0;
            if trackType == 1
                [trackList,noTracksFound] = trackLengthThreshold(app.holdAll(app.allSelected(1)).track,lenThresh);
            elseif trackType == 3
                if app.is3D
                    [~,~,perfectTrackIdxs] = getTrackingWeights3d(app.holdAll(app.allSelected(1)).track);
                else
                    [~,~,perfectTrackIdxs] = getTrackingWeights(app.holdAll(app.allSelected(1)).track);
                end
                [trackList,noTracksFound] = trackLengthThreshold(app.holdAll(app.allSelected(1)).track,lenThresh,perfectTrackIdxs);
            elseif trackType == 5
                [~,centerIdx] = edgeCenterMito(app.holdAll(app.allSelected(1)).track,app.holdAll(app.allSelected(1)).mitoCoM,size(app.holdAll(app.allSelected(1)).ImOG,3),app.is3D);
                [trackList,noTracksFound] = trackLengthThreshold(app.holdAll(app.allSelected(1)).track,lenThresh,centerIdx);
            elseif trackType == 6
                [edgeIdx,~] = edgeCenterMito(app.holdAll(app.allSelected(1)).track,app.holdAll(app.allSelected(1)).mitoCoM,size(app.holdAll(app.allSelected(1)).ImOG,3),app.is3D);
                [trackList,noTracksFound] = trackLengthThreshold(app.holdAll(app.allSelected(1)).track,lenThresh,edgeIdx);
            end
            
            if noTracksFound
                ImOG = app.holdAll(app.selectedIm).ImOG;
                
                %plot track centroids
                imagesc(app.TrackFig,255-ImOG(:,:,1))
                app.TrackFig.Colormap = gray;
                return
            end
%             if trackType == 1
%                 trackList = app.holdAll(figNum).track;
%             elseif trackType == 2
%                 trackList = app.keptTracks(figNum).track;
%             elseif trackType == 3
%                 trackList = app.holdAll(figNum).allPerfectTracksFinal;
%             elseif trackType == 5
%                 [~,app.centerMito] = edgeCenterMito(app.holdAll(app.selectedIm).track,app.holdAll(app.selectedIm).mitoCoM,size(app.holdAll(app.selectedIm).ImOG,3));
%                 trackList = app.holdAll(figNum).track(app.centerMito);
%             elseif trackType == 6
%                 [app.edgeMito,~] = edgeCenterMito(app.holdAll(app.selectedIm).track,app.holdAll(app.selectedIm).mitoCoM,size(app.holdAll(app.selectedIm).ImOG,3));
%                 trackList = app.holdAll(figNum).track(app.edgeMito);
%             end
            
            if app.outlineOn
                updateTrackFigObject(app,app.selectedIm,trackList)
                app.LoadingLabel.Visible = false;
                
                return
            end
            
            ImOG = app.holdAll(figNum).ImOG;
            %plot track centroids
            if app.is3D
                imagesc(app.TrackFig,255-ImOG(:,:,1,app.zPlaneSelected))
%                 axis image
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                app.TrackFig.ZLim = [0,size(ImOG,4)*app.zRatio];
                app.TrackFig.DataAspectRatio = [app.zRatio*app.zAspectRatio,app.zRatio*app.zAspectRatio,1];

                hold(app.TrackFig,"on")
                for i = 1:length(trackList)
                    plotWeightedCentroidApp3D(app,trackList(i))
                    if trackType == 1 || trackType == 2
                        plotFissionEventsApp3D(app,trackList,i)
                        plotFusionEventsApp3D(app,trackList,i)
                    end
                end
            else
                imagesc(app.TrackFig,255-ImOG(:,:,1))
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                hold(app.TrackFig,"on")
                
                
                for i = 1:length(trackList)
                    plotWeightedCentroidApp(app,trackList(i))
                    if trackType == 1 || trackType == 2
                        plotFissionEventsApp(app,trackList,i)
                        plotFusionEventsApp(app,trackList,i)
                    end
                end
            end
            app.LoadingLabel.Visible = false;
            hold(app.TrackFig,"off")
        end
        
        function updateTrackFigSingle(app,figNum,trackNum)
            ImOG = app.holdAll(figNum).ImOG;
            if app.is3D
                %plot track centroids
                imagesc(app.TrackFig,255-ImOG(:,:,1,app.zPlaneSelected))
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                app.TrackFig.ZLim = [0,size(ImOG,4)*app.zRatio];
                app.TrackFig.DataAspectRatio = [app.zRatio*app.zAspectRatio,app.zRatio*app.zAspectRatio,1];
                

                hold(app.TrackFig,"on")
                
                plotWeightedCentroidApp3D(app,app.holdAll(figNum).track(trackNum))
            else
                %plot track centroids
                imagesc(app.TrackFig,255-ImOG(:,:,1))
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                hold(app.TrackFig,"on")
                
                plotWeightedCentroidApp(app,app.holdAll(figNum).track(trackNum))
            end
            hold(app.TrackFig,"off")
        end
        
        function updateTrackFigObject(app,figNum,trackNum)
            ImOG = app.holdAll(figNum).ImOG;
            
            if app.is3D
                imagesc(app.TrackFig,255-ImOG(:,:,1,app.zPlaneSelected))
%                 axis image
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                app.TrackFig.ZLim = [0,size(ImOG,4)*app.zRatio];
                app.TrackFig.DataAspectRatio = [app.zRatio*app.zAspectRatio,app.zRatio*app.zAspectRatio,1];

                hold(app.TrackFig,"on")
                for i = 1:length(trackNum)
                    all1=[];
                    all2=[];
                    all3=[];
                    for frameNum = 1:size(trackNum(i).ConvexHull)
                        all1 = [all1;trackNum(i).ConvexHull{frameNum}(:,1)];
                        all2 = [all2;trackNum(i).ConvexHull{frameNum}(:,2)];
                        all3 = [all3;trackNum(i).ConvexHull{frameNum}(:,3)*app.zRatio];
                    end
                    scatter3(app.TrackFig,all1(:),all2(:),all3(:))
                end
                hold(app.TrackFig,"off")
%                 addedAll = zeros(size(ImOG,1),size(ImOG,2),size(ImOG,4));
%                 addedAllTemp = addedAll;
%                 for plotTrack = 1:length(trackNum)
%                     labelImage = plotLables3D(trackNum,app.holdAll(app.selectedIm).extra.L,plotTrack);
%                     for frameNum = 1:size(labelImage,4)
%                         for zPos = 1:size(labelImage,3)
%                             currIm = labelImage(:,:,zPos,frameNum);
%                             addedAllTemp(currIm>0) = frameNum;
%                             addedAll(:,:,zPos) = addedAllTemp(:,:,zPos);
%                         end
%                     end
%                 end
%                 imagesc(app.TrackFig,addedAll)
%                 app.TrackFig.Colormap = jet;
            else
                imagesc(app.TrackFig,255-ImOG(:,:,1))
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                hold(app.TrackFig,'on')
                for i = 1:length(trackNum)
                    all1 = trackNum(i).Extrema(:,1:2:end);
                    all2 = trackNum(i).Extrema(:,2:2:end);
                    plot(app.TrackFig,all1(:),all2(:))
                end
                hold(app.TrackFig,'off')

%                 addedAll = zeros(size(ImOG,1),size(ImOG,2));
%                 
%                 for plotTrack = 1:length(trackNum)
%                     labelImage = plotLables(trackNum,app.holdAll(app.selectedIm).extra.L,plotTrack);
%                     for frameNum = 1:size(labelImage,3)
%                         currIm = labelImage(:,:,frameNum);
%                         addedAll(currIm>0) = frameNum;
%                     end
%                 end
%                 imagesc(app.TrackFig,addedAll)
%                 app.TrackFig.Colormap = jet;
            end
        end
        
        function plotWeightedCentroidApp(app,track)
            all1 = track.WeightedCentroid(:,1:2:end);
            all2 = track.WeightedCentroid(:,2:2:end);
            
            plot(app.TrackFig,all1(1),all2(1),'-o',...
                'LineWidth',0.5,...
                'MarkerSize',5,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','b')
            plot(app.TrackFig,all1(end),all2(end),'-o',...
                'LineWidth',0.5,...
                'MarkerSize',5,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','r')
            plot(app.TrackFig,all1(:),all2(:),'k','LineWidth',1);
            end
            
        function plotWeightedCentroidApp3D(app,track)
            all1 = track.WeightedCentroid(:,1:3:end);
            all2 = track.WeightedCentroid(:,2:3:end);
            all3 = track.WeightedCentroid(:,3:3:end)*app.zRatio;
            plot3(app.TrackFig,all1(1),all2(1),all3(1),'-o',...
                'LineWidth',0.5,...
                'MarkerSize',5,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','b')
            plot3(app.TrackFig,all1(end),all2(end),all3(end),'-o',...
                'LineWidth',0.5,...
                'MarkerSize',5,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','r')
            p = plot3(app.TrackFig,all1(:),all2(:),all3(:),'k','LineWidth',1);
        end
        
        function plotFissionEventsApp(app,track,trackNum)
            if nnz(track(trackNum).fission)>0
                fissionTrack = track(trackNum).fission(track(trackNum).fission>0);
                fissionFrame = track(trackNum).frame(track(trackNum).fission>0)-1;
                OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                while isempty(OGTrackFrame)
                    fissionFrame = fissionFrame-1;
                    OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                end
                
                OGTrackCentroid1 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*2-1);
                OGTrackCentroid2 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*2);
                connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid(1)];
                connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid(2)];
                
                plot(app.TrackFig,connect1,connect2,':x','LineWidth',2,'Color','#7E2F8E')
            end
        end
        
        function plotFissionEventsApp3D(app,track,trackNum)
            if nnz(track(trackNum).fission)>0
                fissionTrack = track(trackNum).fission(track(trackNum).fission>0);
                fissionFrame = track(trackNum).frame(track(trackNum).fission>0)-1;
                OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                while isempty(OGTrackFrame)
                    fissionFrame = fissionFrame-1;
                    OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                end
            
                OGTrackCentroid1 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*3-2);
                OGTrackCentroid2 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*3-1);
                OGTrackCentroid3 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*3);
                connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid(1)];
                connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid(2)];
                connect3 = [OGTrackCentroid3,track(trackNum).WeightedCentroid(3)]*app.zRatio;
            
                plot3(app.TrackFig,connect1,connect2,connect3,':x','LineWidth',2,'Color','#7E2F8E')
            end
        end
        
        function plotFusionEventsApp(app,track,trackNum)
            if nnz(track(trackNum).fusion)>0
                fusionTrack = track(trackNum).fusion(track(trackNum).fusion>0);
                fusionFrame = track(trackNum).frame(track(trackNum).fusion>0)+1;
                OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                while isempty(OGTrackFrame) && fusionFrame < 10000
                    fusionFrame = fusionFrame+1;
                    OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                end
                
                if fusionFrame == 10000
                    return
                end
                
                fusionIDX = find(track(trackNum).fusion>0);
                OGTrackCentroid1 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*2-1);
                OGTrackCentroid2 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*2);
                connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid((fusionIDX)*2-1)];
                connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid((fusionIDX)*2)];
                
                plot(app.TrackFig,connect1,connect2,':h','LineWidth',1,'Color','#77AC30')
            end
        end
        
        function plotFusionEventsApp3D(app,track,trackNum)
            if nnz(track(trackNum).fusion)>0
                fusionTrack = track(trackNum).fusion(track(trackNum).fusion>0);
                fusionFrame = track(trackNum).frame(track(trackNum).fusion>0)+1;
                OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                while isempty(OGTrackFrame) && fusionFrame < 10000
                    fusionFrame = fusionFrame+1;
                    OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                end
                
                if fusionFrame == 10000
                    return
                end
            
                fusionIDX = find(track(trackNum).fusion>0);
                OGTrackCentroid1 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*3-2);
                OGTrackCentroid2 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*3-1);
                OGTrackCentroid3 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*3);
                connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid((fusionIDX)*3-2)];
                connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid((fusionIDX)*3-1)];
                connect3 = [OGTrackCentroid3,track(trackNum).WeightedCentroid((fusionIDX)*3)]*app.zRatio;
            
                plot3(app.TrackFig,connect1,connect2,connect3,':h','LineWidth',1,'Color','#77AC30')
            end
        end
        
        function createGroup(app,groupName)
            
            app.LoadingLabel.Visible = true;
            pause(0.02)
            
            numFiles = length(app.allSelected);
            trackType = str2num(app.trackTypeGrouping);
            lenThresh = app.lengthThreshGrouping;

            if trackType == 1
                holdTracks = trackLengthThreshold(app.holdAll(app.allSelected(1)).track,lenThresh);
                for fileNum = 2:numFiles
                    preNextTrack = app.holdAll(app.allSelected(fileNum)).track;
                    nextTrack = trackLengthThreshold(preNextTrack,lenThresh);
                    holdTracks = [holdTracks,nextTrack];
                end
            elseif trackType == 2
                [~,~,perfectTrackIdxs] = getTrackingWeights(app.holdAll(app.allSelected(1)).track);
                holdTracks = trackLengthThreshold(app.holdAll(app.allSelected(1)).track,lenThresh,perfectTrackIdxs);
                for fileNum = 2:numFiles
                    [~,~,perfectTrackIdxs] = getTrackingWeights(app.holdAll(app.allSelected(fileNum)).track);
                    preNextTrack = app.holdAll(app.allSelected(fileNum)).track;
                    nextTrack = trackLengthThreshold(preNextTrack,lenThresh,perfectTrackIdxs);
                    holdTracks = [holdTracks,nextTrack];
                end
            elseif trackType == 3
                [~,centerIdx] = edgeCenterMito(app.holdAll(app.allSelected(1)).track,app.holdAll(app.allSelected(1)).mitoCoM,size(app.holdAll(app.allSelected(1)).ImOG,3),app.is3D);
                holdTracks = trackLengthThreshold(app.holdAll(app.allSelected(1)).track,lenThresh,centerIdx);
                for fileNum = 2:numFiles
                    [~,centerIdx] = edgeCenterMito(app.holdAll(app.allSelected(fileNum)).track,app.holdAll(app.allSelected(fileNum)).mitoCoM,size(app.holdAll(app.allSelected(fileNum)).ImOG,3),app.is3D);
                    preNextTrack = app.holdAll(app.allSelected(fileNum)).track;
                    nextTrack = trackLengthThreshold(preNextTrack,lenThresh,centerIdx);
                    holdTracks = [holdTracks,nextTrack];
                end
            elseif trackType == 4
                [edgeIdx,~] = edgeCenterMito(app.holdAll(app.allSelected(1)).track,app.holdAll(app.allSelected(1)).mitoCoM,size(app.holdAll(app.allSelected(1)).ImOG,3),app.is3D);
                holdTracks = trackLengthThreshold(app.holdAll(app.allSelected(1)).track,lenThresh,edgeIdx);
                for fileNum = 2:numFiles
                    [edgeIdx,~] = edgeCenterMito(app.holdAll(app.allSelected(fileNum)).track,app.holdAll(app.allSelected(fileNum)).mitoCoM,size(app.holdAll(app.allSelected(fileNum)).ImOG,3),app.is3D);
                    preNextTrack = app.holdAll(app.allSelected(fileNum)).track;
                    nextTrack = trackLengthThreshold(preNextTrack,lenThresh,edgeIdx);
                    holdTracks = [holdTracks,nextTrack];
                end
            end

            if app.is3D
                holdDyn = getDynamics3d(app.holdAll(app.allSelected(1)).micronPerPixel,app.holdAll(app.allSelected(1)).secondsPerFrame,holdTracks,app.zDistance);
            else
                holdDyn = getDynamics(app.holdAll(app.allSelected(1)).micronPerPixel,app.holdAll(app.allSelected(1)).secondsPerFrame,holdTracks);
            end
            holdBoth = holdTracks;

            for i = 1:length(holdTracks)
                holdBoth(i).speed = holdDyn.speed{i};
                holdBoth(i).distance = holdDyn.distance{i};
                holdBoth(i).displacement = holdDyn.displacement{i};
                holdBoth(i).velocity = holdDyn.velocity{i};
            end

            app.groupDyn = [app.groupDyn,{holdDyn}];
            app.groupList = [app.groupList,{holdBoth}];
            app.groupListName = [app.groupListName,groupName];
            app.GroupsDropDown.Enable = true;
            
            updateGroupList(app)
            
%             app.ListBox.Value = app.groupListName(1);
            app.AddtographButton.Enable = true;
            app.SavetotxtButton.Enable = true;
            
            app.LoadingLabel.Visible = false;
        end
        
        function updateGroupList(app)
            app.GroupsDropDownValueChanged(app)
            app.GroupsDropDown.Items = app.groupListName;
            app.GroupsDropDown.ItemsData = app.groupList;
        end
        
        function updateGraph(app)
            
            cla(app.UIAxes,"reset")
            
            toGraph = app.graphGroups;
            
            if app.logOn
                for i = 1:length(app.graphGroups)
                    toGraph{i} = log10(app.graphGroups{i});
                end
            end
            
            if app.HistogramButton.Value
                for i = 1:length(toGraph)
                    histogram(app.UIAxes,toGraph{i},40,'Normalization','probability')
                    hold(app.UIAxes,'on')
                end
                app.UIAxes.YLabel.String = "Fraction of Mitochondria";
                for i = 1:length(app.ListBox.Items)
                    if strcmp(app.ListBox.ItemsData{i},app.allSelectedParams{1})
                        app.UIAxes.XLabel.String = app.ListBox.Items{i};
                    end
                end
            elseif app.MedianBarButton.Value
                for i = 1:length(toGraph)
                    barToPlot(i) = nanmedian(toGraph{i});
                    hold(app.UIAxes,'on')
                end
                bar(app.UIAxes,barToPlot)
                app.UIAxes.XLabel.String = "Group number";
                for i = 1:length(app.ListBox.Items)
                    if strcmp(app.ListBox.ItemsData{i},app.allSelectedParams{1})
                        app.UIAxes.YLabel.String = app.ListBox.Items{i};
                    end
                end
            end
            hold(app.UIAxes,'off')
            
            dataList = [];
            labelList = [];
            for i = 1:length(toGraph)
                dataList = [dataList;toGraph{i}(:)];
                labelList = [labelList;ones(length(toGraph{i}),1)*i];
            end
            
            p = anova1(dataList,labelList,"off");
            
            if ~isnan(p)
                app.pvalueEditField.Value = p; 
            else
                app.pvalueEditField.Value = 1;
            end
            
        end
    end
    
    methods (Access = public)
        
        function groupingParams(app,newLengthThresh,newTrackType,groupName)
            app.lengthThreshGrouping = newLengthThresh;
            app.trackTypeGrouping = newTrackType;
            createGroup(app,groupName);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadingLabel.Visible = true;
            app.Start2DButton.Enable = false;
            app.Start3DButton.Enable = false;
            app.ResetButton.Enable = false;
            
            f = waitbar(0,'Loading Mitometer');
            pause(.05)
            
            waitbar(.33,f,'Loading Mitometer');
            pause(0.05)
            
            waitbar(.67,f,'Loading Mitometer');
            pause(0.05)
            
            waitbar(1,f,'Loading Mitometer');
            pause(0.05)
            
            close(f)
            
            app.LoadingLabel.Visible = false;
            app.Start2DButton.Enable = true;
            app.Start3DButton.Enable = true;
            app.ResetButton.Enable = true;
        end

        % Button pushed function: Start2DButton
        function Start2DButtonPushed(app, event)
            % Change button name to "Processing"
            app.Start2DButton.Text = 'Processing...';
            app.Start2DButton.FontSize = 30;
            % Put text on top of icon
            app.Start2DButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.Start2DButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.Start2DButton.Icon = wbar;
            % Loop through something and update waitbar


            prompt2D = {'What is your pixel size, in microns per pixel? ','What is your time between frames, in seconds? '};
            dlgtitle2D = 'Start 2D';
            dims2D = [1 35];
            definput2D = {'0.0879','1'};
            answer2D = inputdlg(prompt2D,dlgtitle2D,dims2D,definput2D);
            app.micronPerPixel = str2double(answer2D{1});
            app.secondsPerFrame = str2double(answer2D{2});
            
            if size(app.ImAll)==0
                [ImAllTemp,~,fileNameTemp,app.path] = openTifs();
                app.ImAll = ImAllTemp;
                app.fileName = fileNameTemp;
            else
                [ImAllTemp,~,fileNameTemp,app.path] = openTifs();
                app.ImAll = [app.ImAll,ImAllTemp];
                app.fileName = [app.fileName, fileNameTemp];
            end
            app.totalNumIms = length(app.ImAll);
            app.Start3DButton.Enable = false;
            
            numIms = length(ImAllTemp);
            tempList = {};
            for imNum = 1:numIms
                app.Start2DButton.Text = strcat("Image ",num2str(imNum)," of ",num2str(numIms));
                % Update image data (royalblue)
                currentProg = min(round((size(wbar,2)-2)*((imNum-1)/numIms)),size(wbar,2)-2);
                app.Start2DButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.Start2DButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.Start2DButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                % Pause to slow down animation
                pause(0.002)
                
                % User Image
                ImOG = ImAllTemp{imNum}(:,:,:);
                if app.frameThreshSeconds == -1
                    useframeThreshSeconds = size(ImOG,3);
                else
                    useframeThreshSeconds = app.frameThreshSeconds;
                end
                holdIms(imNum).micronPerPixel = app.micronPerPixel;
                holdIms(imNum).secondsPerFrame = app.secondsPerFrame;
                holdIms(imNum).ImOG = ImOG;
                holdIms(imNum).fileName = fileNameTemp{imNum};
                holdIms(imNum).path = app.path;
                
                %remove diffuse bg and normalize image intensity
                [ImBgRemoved,ImMinMed,ImMedFiltered] = diffuseBgRemove(ImOG,(app.minArea)/app.micronPerPixel,(1)/app.micronPerPixel);
                holdIms(imNum).ImBgRemoved = ImBgRemoved;
                holdIms(imNum).ImMinMed = ImMinMed;
                holdIms(imNum).ImMedFiltered = ImMedFiltered;
                
                %find optimal gaussian filter std Dev and hard threshold value
                if ~app.gaussCheckbox.Value || ~app.threshCheckbox.Value
                    [app.sig,app.thr,costs] = optimizeSigmaThresh(ImBgRemoved);
                    holdIms(imNum).costs = costs;
                else
                    holdIms(imNum).costs = [];
                end
                if app.gaussCheckbox.Value
                    app.sig = app.customSigVal.Value;
                end
                if app.threshCheckbox.Value
                    app.thr = app.customThrVal.Value;
                end
                holdIms(imNum).sig = app.sig;
                holdIms(imNum).thr = app.thr;
                
                %gaussian filter for low-pass filter and smooth discontinuities.
                ImGaussFiltered = gaussFilter(ImBgRemoved,app.sig);
                holdIms(imNum).ImGaussFiltered = ImGaussFiltered;
                
                %threshold image to remove adjacent mitochondria connections and bg.
                ImMask = thresholdImage(ImGaussFiltered,app.thr);
                holdIms(imNum).ImMask = ImMask;
                
                %apply mask
                ImMaskThresholded = areaThreshold(ImMask,app.minArea/(app.micronPerPixel^2),app.maxArea/(app.micronPerPixel^2));
                Im = ImMaskThresholded.*ImOG;
                holdIms(imNum).ImMaskThresholded = ImMaskThresholded;
                holdIms(imNum).Im = Im;
                
                if ~app.customWeightCheckbox.Value
                    %Preliminary tracking to set weighting
                    weightsPrelim = ones(1,6)*1/6;
                    trackPrelim = trackMitochondria(Im,ImOG,weightsPrelim,app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,0,1);
                    %Get the weights
                    [app.weights,~] = getTrackingWeights(trackPrelim);
                end
                holdIms(imNum).weightsFinal = app.weights;
                
                %Real tracking
                if ~app.fisFusCheckbox.Value
                    [track,mitoCoM,extra] = trackMitochondria(Im,ImOG,app.weights(1:6),app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds);
                else
                    [track,mitoCoM,extra] = trackMitochondria(Im,ImOG,app.weights(1:6),app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,0,1);
                end
                holdIms(imNum).track = track;
                holdIms(imNum).mitoCoM = mitoCoM;
                holdIms(imNum).extra = extra;
                
                %Get the weights and perfect tracks
                [~,allPerfectTracksFinal] = getTrackingWeights(track);
                holdIms(imNum).allPerfectTracksFinal = allPerfectTracksFinal;
                
                %Get dynamics
                dynamics = getDynamics(app.micronPerPixel,app.secondsPerFrame,track);
                holdIms(imNum).dynamics = dynamics;
                
                keptTracksTemp(imNum).track = track;
                
                tempList = [tempList, fileNameTemp{imNum}];
            end
            app.listAll = [app.listAll,tempList];
            app.holdAll = [app.holdAll,holdIms];
            app.keptTracks = [app.keptTracks,keptTracksTemp];
            app.ImageList.Items = app.listAll;
            app.ImageList.ItemsData = (1:length(app.listAll));
            
            % remove waitbar
            app.Start2DButton.Icon = '';
            % Change button name
            app.Start2DButton.Text = 'Add 2D';
            app.Start2DButton.FontSize = 60;
            
            app.ImageList.Value = 1;
            ImageListValueChanged(app,event)
            SelecthighlightedButtonPushed(app,event)
            
            app.selectedIm = 1;
            updateSegFigs(app,app.selectedIm,1)
            updateTrackFig(app,1,app.selectedIm)
        end

        % Button pushed function: Start3DButton
        function Start3DButtonPushed(app, event)
            addpath(strcat(pwd,'/3D'));
            
            % Change button name to "Processing"
            app.Start3DButton.Text = 'Processing...';
            app.Start3DButton.FontSize = 30;
            % Put text on top of icon
            app.Start3DButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.Start3DButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.Start3DButton.Icon = wbar;
            % Loop through something and update waitbar
            
            prompt3D = {'What is your pixel size, in microns per pixel? ','What is your time between frames, in seconds? ', 'How many z-planes in the stack? ', 'What is the axial distance, in microns? '};
            dlgtitle3D = 'Start 2D';
            dims3D = [1 35];
            definput3D = {'0.105','10', '21', '0.45'};
            answer3D = inputdlg(prompt3D,dlgtitle3D,dims3D,definput3D);
            app.micronPerPixel = str2double(answer3D{1});
            app.secondsPerFrame = str2double(answer3D{2});
            app.zPlane = str2double(answer3D{3});
            app.zDistance = str2double(answer3D{4});
            
            if size(app.ImAll) == 0
                [ImAllTemp,~,fileNameTemp,app.path] = openTifs(app.zPlane);
                app.ImAll = ImAllTemp;
                app.fileName = fileNameTemp;
            else
                [ImAllTemp,~,fileNameTemp,app.path] = openTifs(app.zPlane);
                app.ImAll = [app.ImAll,ImAllTemp];
                app.fileName = [app.fileName, fileNameTemp];
            end
            app.totalNumIms = length(app.ImAll);
            app.Start2DButton.Enable = false;
            app.is3D = true;
            
            
            numIms = length(ImAllTemp);
            tempList = {};

            for imNum = 1:numIms
                app.Start3DButton.Text = strcat("Image ",num2str(imNum)," of ",num2str(numIms));
                % Update image data (royalblue)
                currentProg = min(round((size(wbar,2)-2)*(imNum-1/numIms)),size(wbar,2)-2);
                app.Start3DButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.Start3DButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.Start3DButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                % Pause to slow down animation
                pause(0.002)
                
                % User Image
                ImStack = ImAllTemp{imNum};
                holdIms(imNum).micronPerPixel = app.micronPerPixel;
                holdIms(imNum).secondsPerFrame = app.secondsPerFrame;
                holdIms(imNum).ImOG = ImStack;
                holdIms(imNum).fileName = fileNameTemp{imNum};
                holdIms(imNum).path = app.path;
                numStacks = size(ImStack,4);
                if app.frameThreshSeconds == -1
                    useframeThreshSeconds = size(ImStack,3);
                else
                    useframeThreshSeconds = app.frameThreshSeconds;
                end
                %remove diffuse bg
                clear temp
                for stackNum = 1:numStacks
                    sprintf("Plane %d of %d.",stackNum,numStacks)
                    temp(:,:,:) = ImStack(:,:,:,stackNum);
                    [ImBgRemoved(:,:,:,stackNum),ImMinMedProj(:,:,:,stackNum),ImMedFilteredProj(:,:,:,stackNum)] = diffuseBgRemove(temp,app.minArea/app.micronPerPixel,1/app.micronPerPixel);
                    meanInt(stackNum) = mean(mean(mean(ImBgRemoved(:,:,1:10,stackNum))));
                end
                holdIms(imNum).ImBgRemoved = ImBgRemoved;
                holdIms(imNum).ImMinMed = ImMinMedProj;
                holdIms(imNum).ImMedFiltered = ImMedFilteredProj;
                
                %find optimal gaussian filter std Dev and hard threshold value
                clear temp
                if ~app.gaussCheckbox.Value || ~app.threshCheckbox.Value
                    [app.sig,app.thr,costs{stackNum}] = optimizeSigmaThresh(ImBgRemoved(:,:,:,find(meanInt==max(meanInt))));
                    holdIms(imNum).costs = costs;
                else
                    holdIms(imNum).costs = [];
                end
                
                if app.gaussCheckbox.Value
                    app.sig = app.customSigVal.Value;
                end
                
                if app.threshCheckbox.Value
                    app.thr = app.customThrVal.Value;
                end
                
                holdIms(imNum).sig = app.sig;
                holdIms(imNum).thr = app.thr;
                
                ImGaussFiltered = zeros(size(ImStack),'like',ImStack);
                ImMask = zeros(size(ImStack),'like',ImStack);
                ImMaskThresholded = zeros(size(ImStack),'like',ImStack);
                Im = zeros(size(ImStack),'like',ImStack);
                for stackNum = 1:numStacks
                    temp(:,:,:) = ImBgRemoved(:,:,:,stackNum);
                    %gaussian filter for low-pass filter and smooth discontinuities.
                    ImGaussFiltered(:,:,:,stackNum) = gaussFilter(temp,app.sig);%holdIms(imNum).sig(find(holdIms(imNum).thr==max(holdIms(imNum).thr))));
                    
                    %threshold image to remove adjacent mitochondria connections and bg.
                    ImMask(:,:,:,stackNum) = thresholdImage(ImGaussFiltered(:,:,:,stackNum),app.thr);%max(holdIms(imNum).thr));
                    
                    %area threshold and apply mask
                    ImMaskThresholded(:,:,:,stackNum) = uint8(areaThreshold(ImMask(:,:,:,stackNum),app.minArea/(app.micronPerPixel^2),app.maxArea/app.micronPerPixel^2));
                    Im(:,:,:,stackNum) = ImStack(:,:,:,stackNum).*ImMaskThresholded(:,:,:,stackNum);
                end
                holdIms(imNum).ImGaussFiltered = ImGaussFiltered;
                holdIms(imNum).ImMask = ImMask;
                
                
                clear temp
                numFrames = size(Im,3);
                for frameNum = 1:numFrames
                    temp(:,:,:) = Im(:,:,frameNum,:);
                    CCPreThresh = bwconncomp(temp);
                    statsPreThresh = regionprops3(CCPreThresh);
                    
                    %Remove components existing in only one plane
                    keptComponents = find(mod(statsPreThresh.Centroid(:,3),1)~=0);
                    
                    %Make a new image with only kept components
                    threshIm(:,:,frameNum,:) = ismember(labelmatrix(CCPreThresh),keptComponents);
                end
                holdIms(imNum).ImMaskThresholded = threshIm;
                
                ImFinal = Im.*uint8(threshIm);
                holdIms(imNum).Im = ImFinal;
                
                if ~app.customWeightCheckbox.Value
                    %Preliminary tracking to set weighting
                    weightsPrelim = ones(1,7)*1/7;
                    trackPrelim = trackMitochondria3d(ImFinal,ImStack,weightsPrelim,app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,app.zDistance,0,1);
                    %Get the weights
                    [app.weights,~] = getTrackingWeights3d(trackPrelim);
                end
                holdIms(imNum).weightsFinal = app.weights;
                
                %Real tracking
                if ~app.fisFusCheckbox.Value
                    [track,mitoCoM,extra] = trackMitochondria3d(ImFinal,ImStack,app.weights,app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,app.zDistance);
                else
                    [track,mitoCoM,extra] = trackMitochondria3d(ImFinal,ImStack,app.weights,app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,app.zDistance,0,1);
                end
                holdIms(imNum).track = track;
                holdIms(imNum).mitoCoM = mitoCoM;
                holdIms(imNum).extra = extra;
                
                %Get the perfect tracks
                [~,allPerfectTracksFinal] = getTrackingWeights3d(track);
                holdIms(imNum).allPerfectTracksFinal = allPerfectTracksFinal;
                
                %Get dynamics
                dynamics = getDynamics3d(app.micronPerPixel,app.secondsPerFrame,track,app.zDistance);
                holdIms(imNum).dynamics = dynamics;
                
                keptTracksTemp(imNum).track = track;
                tempList = [tempList, fileNameTemp{imNum}];
                
            end
                
            app.listAll = [app.listAll,tempList];
            app.holdAll = [app.holdAll,holdIms];
            app.keptTracks = [app.keptTracks,keptTracksTemp];
            app.ImageList.Items = app.listAll;
            app.ImageList.ItemsData = (1:length(app.listAll));
            
            app.holdAll = [app.holdAll,holdIms];
            app.keptTracks = [app.keptTracks,keptTracksTemp];
            
            % remove waitbar
            app.Start3DButton.Icon = '';
            % Change button name
            app.Start3DButton.Text = 'Add 3D';
            app.Start3DButton.FontSize = 60;
            
            app.zRatio = app.zDistance/app.micronPerPixel;
            app.ZAspectRatioSpinner.Enable = true;
            
            app.ImageList.Value = 1;
            ImageListValueChanged(app,event)
            SelecthighlightedButtonPushed(app,event)
            
            app.selectedIm = 1;
            updateSegFigs(app,app.selectedIm,1)
            updateTrackFig(app,1,app.selectedIm)
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
clc
app.groupList
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            confirmClear = sprintf('Do you really want to clear the workspace?');
            buttonConfirmClear = questdlg(confirmClear, 'Clear all', 'Yes', 'No', 'No');
            if ~strcmpi(buttonConfirmClear, 'Yes')
                return;
            else
                delete(app.MitometerUIFigure);
                GUI;
            end
        end

        % Value changed function: customMinArea
        function customMinAreaValueChanged(app, event)
            app.minArea = app.customMinArea.Value;
            
        end

        % Value changed function: customMaxArea
        function customMaxAreaValueChanged(app, event)
            app.maxArea = app.customMaxArea.Value;
            
        end

        % Value changed function: customSigVal
        function customSigValValueChanged(app, event)
            app.sig = app.customSigVal.Value;
            
        end

        % Value changed function: customThrVal
        function customThrValValueChanged(app, event)
            app.thr = app.customThrVal.Value;
            
        end

        % Value changed function: gaussCheckbox
        function gaussCheckboxValueChanged(app, event)
            customGauss = app.gaussCheckbox.Value;
            if customGauss
                app.customSigVal.Enable = true;
            else
                app.customSigVal.Enable = false;
            end
        end

        % Value changed function: threshCheckbox
        function threshCheckboxValueChanged(app, event)
            customThresh = app.threshCheckbox.Value;
            if customThresh
                app.customThrVal.Enable = true;
            else
                app.customThrVal.Enable = false;
            end
        end

        % Value changed function: customDistThresh
        function customDistThreshValueChanged(app, event)
            app.distThreshMicrons = app.customDistThresh.Value;
            
        end

        % Value changed function: customFrameThresh
        function customFrameThreshValueChanged(app, event)
            app.frameThreshSeconds = app.customFrameThresh.Value;
            
        end

        % Value changed function: fisFusCheckbox
        function fisFusCheckboxValueChanged(app, event)
            app.fisFusSkip = app.fisFusCheckbox.Value;
            
        end

        % Value changed function: customWeightCheckbox
        function customWeightCheckboxValueChanged(app, event)
            customWeights = app.customWeightCheckbox.Value;
            if customWeights
                app.customDistWeight.Enable = true;
                app.customAreaWeight.Enable = true;
                app.customMajAxWeight.Enable = true;
                app.customMinAxWeight.Enable = true;
                app.customSolWeight.Enable = true;
                app.customPeriWeight.Enable = true;
                app.customIntWeight.Enable = true;
                app.customZAxWeight.Enable = true;
            else
                app.customDistWeight.Enable = false;
                app.customAreaWeight.Enable = false;
                app.customMajAxWeight.Enable = false;
                app.customMinAxWeight.Enable = false;
                app.customSolWeight.Enable = false;
                app.customPeriWeight.Enable = false;
                app.customIntWeight.Enable = false;
                app.customZAxWeight.Enable = false;
            end
        end

        % Value changed function: customDistWeight
        function customDistWeightValueChanged(app, event)
            app.distWeight = app.customDistWeight.Value;
            
        end

        % Value changed function: customAreaWeight
        function customAreaWeightValueChanged(app, event)
            app.weights(1) = app.customAreaWeight.Value;
            
        end

        % Value changed function: customMajAxWeight
        function customMajAxWeightValueChanged(app, event)
            app.weights(2) = app.customMajAxWeight.Value;
            
        end

        % Value changed function: customMinAxWeight
        function customMinAxWeightValueChanged(app, event)
            app.weights(3) = app.customMinAxWeight.Value;
            
        end

        % Value changed function: customSolWeight
        function customSolWeightValueChanged(app, event)
            app.weights(4) = app.customSolWeight.Value;
            
        end

        % Value changed function: customPeriWeight
        function customPeriWeightValueChanged(app, event)
            app.weights(5) = app.customPeriWeight.Value;
            
        end

        % Value changed function: customIntWeight
        function customIntWeightValueChanged(app, event)
            app.weights(6) = app.customIntWeight.Value;
            
        end

        % Value changed function: customZAxWeight
        function customZAxWeightValueChanged(app, event)
            app.weights(7) = app.customZAxWeight.Value;
            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            frameNum = round(event.Value);
            updateSegFigFrame(app,frameNum,app.figNum)
            app.Spinner.Value = frameNum;
        end

        % Selection changed function: SelectedImageButtonGroup
        function SelectedImageButtonGroupSelectionChanged(app, event)
            figNumTemp = app.SelectedImageButtonGroup.SelectedObject;
            app.figNum = str2double(figNumTemp.Tag);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            frameNum = app.Spinner.Value;
            updateSegFigFrame(app,frameNum,app.figNum)
            app.FrameSlider.Value = frameNum;
        end

        % Button pushed function: PlaySeg
        function PlayButtonPushed(app, event)
            numFrames = size(app.segFigHold.ImOG,3);
            
            app.PlaySeg.Text = '...';
            for frameNum = 1:numFrames
                app.Spinner.Value = frameNum;
                updateSegFigFrame(app,frameNum,app.figNum)
                pause(0.2)
            end
            app.PlaySeg.Text = 'Play';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            trackStr = app.DropDown.Value;
            app.keptTracksThresh.Enable = false;
            app.singleTrack.Enable = false;
            app.ObjectCheckBox.Enable = true;
            if strcmp(trackStr,'All tracks')
                trackType = 1;
                app.trackSave = 1;
            elseif strcmp(trackStr,'Kept tracks')
                trackType = 2;
                app.trackSave = 2;
                app.keptTracksThresh.Enable = true;
            elseif strcmp(trackStr,'Confident tracks')
                trackType = 3;
                app.trackSave = 3;
            elseif strcmp(trackStr,'Single track')
                app.singleTrack.Enable = true;
                app.LoadingLabel.Visible = false;
                app.singleTrack.Limits = [1 length(app.holdAll(app.selectedIm).track)];
                singleTrackValueChanged(app,event)
                return
            elseif strcmp(trackStr,'Perinuclear')
                trackType = 5;
                app.trackSave = 5;
            elseif strcmp(trackStr,'Telenuclear')
                trackType = 6;
                app.trackSave = 6;
            end
            app.trackTypeHold = trackType;
            updateTrackFig(app,trackType,app.selectedIm)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: keptTracksThresh
        function keptTracksThreshValueChanged(app, event)
            app.lengthThresh = app.keptTracksThresh.Value;
            
            updateTrackFig(app,app.trackTypeHold,app.selectedIm)
        end

        % Value changed function: singleTrack
        function singleTrackValueChanged(app, event)
            trackToView = app.singleTrack.Value;
            app.ObjectCheckBox.Enable = true;
            if app.outlineOn
                updateTrackFigObject(app,app.selectedIm,app.holdAll(app.selectedIm).track(trackToView))
%                 labelImage = plotLables(app.holdAll(app.selectedIm).track,app.holdAll(app.selectedIm).extra.L,trackToView);
%                 addedAll = zeros(size(labelImage,1),size(labelImage,2));
%                 for frameNum = 1:size(labelImage,3)
%                     currIm = labelImage(:,:,frameNum);
%                     addedAll(currIm>0) = frameNum;
%                 end
%                 imagesc(app.TrackFig,addedAll)
%                 app.TrackFig.Colormap = jet;
            else
                updateTrackFigSingle(app,app.selectedIm,trackToView)
            end
        end

        % Value changed function: ObjectCheckBox
        function ObjectCheckBoxValueChanged(app, event)
            app.outlineOn = app.ObjectCheckBox.Value;
            DropDownValueChanged(app,event)
        end

        % Value changed function: ImageList
        function ImageListValueChanged(app, event)
            app.allSelected = app.ImageList.Value;
            app.selectedIm = app.allSelected(1);


        end

        % Button pushed function: SavetifstackButton
        function SavetifstackButtonPushed(app, event)
            
            if app.figNum == 1
                imToSave = app.holdAll(app.selectedIm).ImOG;
            elseif app.figNum == 2
                imToSave = app.holdAll(app.selectedIm).ImBgRemoved;
            elseif app.figNum == 3
                if app.is3D
                    for ii = 1:size(app.holdAll(app.selectedIm).ImOG,4)
                        for i = 1:size(app.holdAll(app.selectedIm).ImOG,3)
                            labelIm(:,:,i,ii) = rgb2gray(label2rgb(app.holdAll(app.selectedIm).extra.L{i}(:,:,ii),'hsv','k','shuffle'));
                        end
                    end
                else
                    for i = 1:size(app.holdAll(app.selectedIm).ImOG,3)
                        labelIm(:,:,i) = rgb2gray(label2rgb(app.holdAll(app.selectedIm).extra.L{i},'hsv','k','shuffle'));
                    end
                end
                imToSave = labelIm;
            elseif app.figNum == 4
                imToSave = app.holdAll(app.selectedIm).Im;
            end
            
            defName = app.fileName{app.selectedIm};
            [inputName,savePath,indx] = uiputfile('*.tif','Choose a folder in which to save selected image(s)',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            outputFileName = strcat(savePath,inputName);
            if exist(outputFileName,"file")
                overwritePrompt = sprintf('File already exists. Overwrite?');
                overwriteButton = questdlg(overwritePrompt, 'Overwrite?', 'Overwrite', 'Cancel', 'Overwrite');
                
                if strcmpi(overwriteButton, 'Overwrite')
                    delete(outputFileName)
                    if app.is3D
                        for iii=1:size(imToSave,3)
                            for iv = 1:size(imToSave,4)
                                imwrite(imToSave(:,:,iii,iv), outputFileName, 'WriteMode', 'append',  'Compression','none');
                            end
                        end
                    else
                        for iii=1:size(imToSave,3)
                            imwrite(imToSave(:,:,iii), outputFileName, 'WriteMode', 'append',  'Compression','none');
                        end
                    end
                end
            else
                if app.is3D
                        for iii=1:size(imToSave,3)
                            for iv = 1:size(imToSave,4)
                                imwrite(imToSave(:,:,iii,iv), outputFileName, 'WriteMode', 'append',  'Compression','none');
                            end
                        end
                else
                    for iii=1:size(imToSave,3)
                        imwrite(imToSave(:,:,iii), outputFileName, 'WriteMode', 'append',  'Compression','none');
                    end
                end
            end
        end

        % Button pushed function: SelectedAll
        function SelectedAllPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.allSelected)
                saveName = app.fileName{app.allSelected(i)};
                saveVar = app.holdAll(app.allSelected(i)).track;
                save(strcat(savePath,saveName,"_allTracks",".mat"),"saveVar");
            end
        end

        % Button pushed function: AllAll
        function AllAllPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.holdAll)
                saveName = app.fileName{i};
                saveVar = app.holdAll(i).track;
                save(strcat(savePath,saveName,"_allTracks",".mat"),"saveVar");
            end
        end

        % Button pushed function: SelectedKept
        function SelectedKeptButtonPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.allSelected)
                saveName = app.fileName{app.allSelected(i)};
                saveVar = app.keptTracks(app.allSelected(i)).track;
                save(strcat(savePath,saveName,"_keptTracks",num2str(app.lengthThresh),".mat"),"saveVar");
            end
        end

        % Button pushed function: AllKept
        function AllKeptButtonPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.holdAll)
                saveName = app.fileName{i};
                saveVar = app.keptTracks(i).track;
                save(strcat(savePath,saveName,"_keptTracks",num2str(app.lengthThresh),".mat"),"saveVar");
            end
        end

        % Button pushed function: SelectedPerf
        function SelectedPerfButtonPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.allSelected)
                saveName = app.fileName{app.allSelected(i)};
                saveVar = app.holdAll(app.allSelected(i)).allPerfectTracksFinal;
                save(strcat(savePath,saveName,"_perfectTracks",".mat"),"saveVar");
            end
        end

        % Button pushed function: AllPerf
        function AllPerfButtonPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.holdAll)
                saveName = app.fileName{i};
                saveVar = app.holdAll(i).allPerfectTracksFinal;
                save(strcat(savePath,saveName,"_perfectTracks",".mat"),"saveVar");
            end
        end

        % Button pushed function: CreategroupButton
        function CreategroupButtonPushed(app, event)
            
            app.LoadingLabel.Visible = true;
            pause(0.02)
            
            app.CreategroupButton.Enable = false;
            
            app.callGroupingApp = groupingApp(app,app.lengthThreshGrouping,app.trackTypeGrouping);

            tic
            GroupsDropDownValueChanged(app, event)
            app.LoadingLabel.Visible = false;
        end

        % Close request function: MitometerUIFigure
        function MitometerUIFigureCloseRequest(app, event)
            delete(app.callGroupingApp)
            delete(app)
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.allSelectedParams = app.ListBox.Value;
            nope = 0;
            for i = 1:length(app.allSelectedParams)
                if ~app.is3D
                    if strcmp(app.allSelectedParams{i},'ZAxisLength')
                        nope = 1;
                                
                        app.AddtographButton.Enable = false;
                        app.SavetotxtButton.Enable = false;
                    end
                end
            end
            if app.GroupsDropDown.Enable && ~nope
                app.AddtographButton.Enable = true;
                app.SavetotxtButton.Enable = true;
            end
        end

        % Button pushed function: AddtographButton
        function AddtographButtonPushed(app, event)
            if ~iscell(app.allSelectedParams)
                app.allSelectedParams = {app.allSelectedParams};
            end
            app.selectedGroup = app.GroupsDropDown.Value;

            for i = 1:length(app.selectedGroup)
                if app.is3D
                    medianVals(i).Area = nanmedian(app.selectedGroup(i).Volume);
                    medianVals(i).Perimeter = nanmedian(app.selectedGroup(i).SurfaceArea);
                    medianVals(i).ZAxisLength = nanmedian(app.selectedGroup(i).ZAxisLength);
                else
                    medianVals(i).Area = nanmedian(app.selectedGroup(i).Area);
                    medianVals(i).Perimeter = nanmedian(app.selectedGroup(i).Perimeter);
                    medianVals(i).zAxisLength = 0;
                    
                end
                medianVals(i).MajorAxisLength = nanmedian(app.selectedGroup(i).MajorAxisLength);
                medianVals(i).MinorAxisLength = nanmedian(app.selectedGroup(i).MinorAxisLength);
                medianVals(i).Solidity = nanmedian(app.selectedGroup(i).Solidity);
                medianVals(i).MeanIntensity = nanmedian(app.selectedGroup(i).MeanIntensity);
                medianVals(i).fission = nnz([app.selectedGroup.fission])/length(app.selectedGroup);
                medianVals(i).fusion = nnz([app.selectedGroup.fusion])/length(app.selectedGroup);
                medianVals(i).speed = nanmedian(app.selectedGroup(i).speed);
                medianVals(i).distance = nanmedian(app.selectedGroup(i).distance);
                medianVals(i).displacement = nanmedian(app.selectedGroup(i).displacement);
                medianVals(i).velocity = nanmedian(app.selectedGroup(i).velocity);
            end
            
            app.graphGroups = [app.graphGroups,{[medianVals.(app.allSelectedParams{1})]}];
                        
            updateGraph(app)
        end

        % Value changed function: GroupsDropDown
        function GroupsDropDownValueChanged(app, event)
            app.selectedGroup = app.GroupsDropDown.Value;
            app.allSelectedParams = app.ListBox.Value;

        end

        % Button pushed function: SavetotxtButton
        function SavetotxtButtonPushed(app, event)
            selStruc = app.selectedGroup;
            app.selectedGroup = app.GroupsDropDown.Value;
            maxFrames = max([app.selectedGroup.frame]);
            frameArray = 1:maxFrames;
            numTracks = length(selStruc);
            fnames = app.allSelectedParams;


            savePath = uigetdir(app.path,'Select a folder to save your files. Text files will be named "date and time"+"_group name"+"_parameter name"');
            for i = 1:length(app.GroupsDropDown.Items)
                if isequaln(app.GroupsDropDown.ItemsData{i},app.selectedGroup)
                    groupName = app.GroupsDropDown.Items{i};
                end
            end
            
            
            for i = 1:length(fnames)
                out = nan(numTracks,maxFrames);
                if app.is3D 
                    if strcmp(fnames{i},'Area')
                        fnames{i} = 'Volume';
                    elseif strcmp(fnames{i},'Perimeter')
                        fnames{i} = 'SurfaceArea';
                    end
                end
                for j = 1:numTracks
                    [~,loc] = ismember(selStruc(j).frame,frameArray);
                    out(j,(loc)) = [selStruc(j).(fnames{i})];
                end
                strcat(savePath,datestr(now,'yyyymmdd_'),groupName,'_',fnames{i},'.txt')
                writematrix(out,strcat(savePath,"/",datestr(now,'yyyymmdd_'),groupName,'_',fnames{i},'.txt'))
            end
        end

        % Value changed function: LogscaleCheckBox
        function LogscaleCheckBoxValueChanged(app, event)
            app.logOn = app.LogscaleCheckBox.Value;
            updateGraph(app)
        end

        % Button pushed function: ResetgraphButton
        function ResetgraphButtonPushed(app, event)
            cla(app.UIAxes,'reset')
            app.graphGroups = {};
        end

        % Selection changed function: GraphtypeButtonGroup
        function GraphtypeButtonGroupSelectionChanged(app, event)
            updateGraph(app)
        end

        % Value changing function: ZPlaneSlider
        function ZPlaneSliderValueChanging(app, event)
            app.zPlaneSelected = round(event.Value);
            app.Spinner_2.Value = app.zPlaneSelected;
            updateSegFigs(app,1,app.figNum)

        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            app.zPlaneSelected = app.Spinner_2.Value;
            app.ZPlaneSlider.Value = app.zPlaneSelected;
            updateSegFigs(app,1,app.figNum)

        end

        % Value changed function: ZAspectRatioSpinner
        function ZAspectRatioSpinnerValueChanged(app, event)
            app.zAspectRatio = app.ZAspectRatioSpinner.Value;
            updateTrackFig(app,1,app.figNum)
        end

        % Button pushed function: SelecthighlightedButton
        function SelecthighlightedButtonPushed(app, event)
            updateSegFigs(app,app.selectedIm,1)
            updateTrackFig(app,1,app.selectedIm)
            DropDownValueChanged(app,event)
        end

        % Button pushed function: SelectedButton
        function SelectedButtonPushed(app, event)
            app.LoadingLabel.Visible = true;
            pause(0.02)

            app.keptTracksThresh.Enable = true;
            
            lenThresh = app.keptTracksThresh.Value;
            
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            
            for numSelected = 1:length(app.allSelected)
                if app.trackSave == 1
                    appendText = "_allTracks_thresh";
                    [trackList,~] = trackLengthThreshold(app.holdAll(app.allSelected(numSelected)).track,lenThresh);
                elseif app.trackSave == 3
                    appendText = "_confidentTracks_thresh";
                    if app.is3D
                        [~,~,perfectTrackIdxs] = getTrackingWeights3d(app.holdAll(app.allSelected(numSelected)).track);
                    else
                        [~,~,perfectTrackIdxs] = getTrackingWeights(app.holdAll(app.allSelected(numSelected)).track);
                    end
                    [trackList,~] = trackLengthThreshold(app.holdAll(app.allSelected(numSelected)).track,lenThresh,perfectTrackIdxs);
                elseif app.trackSave == 5
                    appendText = "_perinuclearTracks_thresh";
                    [~,centerIdx] = edgeCenterMito(app.holdAll(app.allSelected(numSelected)).track,app.holdAll(app.allSelected(numSelected)).mitoCoM,size(app.holdAll(app.allSelected(numSelected)).ImOG,3),app.is3D);
                    [trackList,~] = trackLengthThreshold(app.holdAll(app.allSelected(numSelected)).track,lenThresh,centerIdx);
                elseif app.trackSave == 6
                    appendText = "_telenuclearTracks_thresh";
                    [edgeIdx,~] = edgeCenterMito(app.holdAll(app.allSelected(numSelected)).track,app.holdAll(app.allSelected(numSelected)).mitoCoM,size(app.holdAll(app.allSelected(numSelected)).ImOG,3),app.is3D);
                    [trackList,~] = trackLengthThreshold(app.holdAll(app.allSelected(numSelected)).track,lenThresh,edgeIdx);
                end
                
                saveName = app.fileName{app.allSelected(numSelected)};
                save(strcat(savePath,saveName,appendText,num2str(lenThresh),".mat"),"trackList");
            end
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            app.LoadingLabel.Visible = true;
            pause(0.02)

            app.keptTracksThresh.Enable = true;
            
            lenThresh = app.keptTracksThresh.Value;
            
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            
            for numSelected = 1:length(app.holdAll)
                if app.trackSave == 1
                    appendText = "_allTracks_thresh";
                    [trackList,~] = trackLengthThreshold(app.holdAll(numSelected).track,lenThresh);
                elseif app.trackSave == 3
                    appendText = "_confidentTracks_thresh";
                    if app.is3D
                        [~,~,perfectTrackIdxs] = getTrackingWeights3d(app.holdAll(numSelected).track);
                    else
                        [~,~,perfectTrackIdxs] = getTrackingWeights(app.holdAll(numSelected).track);
                    end
                    [trackList,~] = trackLengthThreshold(app.holdAll(numSelected).track,lenThresh,perfectTrackIdxs);
                elseif app.trackSave == 5
                    appendText = "_perinuclearTracks_thresh";
                    [~,centerIdx] = edgeCenterMito(app.holdAll(numSelected).track,app.holdAll(numSelected).mitoCoM,size(app.holdAll(numSelected).ImOG,3),app.is3D);
                    [trackList,~] = trackLengthThreshold(app.holdAll(numSelected).track,lenThresh,centerIdx);
                elseif app.trackSave == 6
                    appendText = "_telenuclearTracks_thresh";
                    [edgeIdx,~] = edgeCenterMito(app.holdAll(numSelected).track,app.holdAll(numSelected).mitoCoM,size(app.holdAll(numSelected).ImOG,3),app.is3D);
                    [trackList,~] = trackLengthThreshold(app.holdAll(numSelected).track,lenThresh,edgeIdx);
                end
                saveName = app.fileName{numSelected};
                save(strcat(savePath,saveName,appendText,num2str(lenThresh),".mat"),"trackList");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MitometerUIFigure = uifigure('Visible', 'off');
            app.MitometerUIFigure.Name = 'Mitometer';
            app.MitometerUIFigure.CloseRequestFcn = createCallbackFcn(app, @MitometerUIFigureCloseRequest, true);
            app.DebugButton = uibutton(app.MitometerUIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Visible = 'off';
            app.DebugButton.Text = 'Debug';
            app.TabGroup = uitabgroup(app.MitometerUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Start2DButton = uibutton(app.HomeTab, 'push');
            app.Start2DButton.ButtonPushedFcn = createCallbackFcn(app, @Start2DButtonPushed, true);
            app.Start2DButton.Text = 'Start 2D';
            app.Start3DButton = uibutton(app.HomeTab, 'push');
            app.Start3DButton.ButtonPushedFcn = createCallbackFcn(app, @Start3DButtonPushed, true);
            app.Start3DButton.Text = 'Start 3D';
            app.ResetButton = uibutton(app.HomeTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MitometerLabel = uilabel(app.HomeTab);
            app.MitometerLabel.Text = 'Mitometer';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.segFigOG = uiaxes(app.SegmentationTab);
            title(app.segFigOG, '')
            xlabel(app.segFigOG, '')
            ylabel(app.segFigOG, '')
            app.segFigOG.XTick = [];
            app.segFigOG.XTickLabel = '';
            app.segFigOG.YTick = [];
            app.segFigOG.YTickLabel = '';
            app.segFigMed = uiaxes(app.SegmentationTab);
            title(app.segFigMed, '')
            xlabel(app.segFigMed, '')
            ylabel(app.segFigMed, '')
            app.segFigMed.XTick = [];
            app.segFigMed.XTickLabel = '';
            app.segFigMed.YTick = [];
            app.segFigMed.YTickLabel = '';
            app.segFigObjects = uiaxes(app.SegmentationTab);
            title(app.segFigObjects, '')
            xlabel(app.segFigObjects, '')
            ylabel(app.segFigObjects, '')
            app.segFigObjects.XTick = [];
            app.segFigObjects.XTickLabel = '';
            app.segFigObjects.YTick = [];
            app.segFigObjects.YTickLabel = '';
            app.segFigFinal = uiaxes(app.SegmentationTab);
            title(app.segFigFinal, '')
            xlabel(app.segFigFinal, '')
            ylabel(app.segFigFinal, '')
            app.segFigFinal.XTick = [];
            app.segFigFinal.XTickLabel = '';
            app.segFigFinal.YTick = [];
            app.segFigFinal.YTickLabel = '';
            app.OriginalImageLabel = uilabel(app.SegmentationTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.DiffuseBackgroundRemovedLabel = uilabel(app.SegmentationTab);
            app.DiffuseBackgroundRemovedLabel.Text = 'Diffuse Background Removed';
            app.ObjectsRandomlyLabeled = uilabel(app.SegmentationTab);
            app.ObjectsRandomlyLabeled.Text = 'Objects Randomly Labeled';
            app.FinalImageLabel = uilabel(app.SegmentationTab);
            app.FinalImageLabel.Text = 'Final Image';
            app.SelectedImageButtonGroup = uibuttongroup(app.SegmentationTab);
            app.SelectedImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectedImageButtonGroupSelectionChanged, true);
            app.SelectedImageButtonGroup.Title = 'Selected Image';
            app.OriginalButton = uitogglebutton(app.SelectedImageButtonGroup);
            app.OriginalButton.Tag = '1';
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.DiffuseButton = uitogglebutton(app.SelectedImageButtonGroup);
            app.DiffuseButton.Tag = '2';
            app.DiffuseButton.Text = 'Diffuse';
            app.ObjectsButton = uitogglebutton(app.SelectedImageButtonGroup);
            app.ObjectsButton.Tag = '3';
            app.ObjectsButton.Text = 'Objects';
            app.FinalButton = uitogglebutton(app.SelectedImageButtonGroup);
            app.FinalButton.Tag = '4';
            app.FinalButton.Text = 'Final';
            app.FrameSliderLabel = uilabel(app.SegmentationTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.SegmentationTab);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.Spinner = uispinner(app.SegmentationTab);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.PlaySeg = uibutton(app.SegmentationTab, 'push');
            app.PlaySeg.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlaySeg.Text = 'Play';
            app.SavetifstackButton = uibutton(app.SegmentationTab, 'push');
            app.SavetifstackButton.ButtonPushedFcn = createCallbackFcn(app, @SavetifstackButtonPushed, true);
            app.SavetifstackButton.Text = 'Save .tif stack';
            app.Spinner_2 = uispinner(app.SegmentationTab);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Enable = 'off';
            app.Spinner_2.Value = 1;
            app.ZPlaneSliderLabel = uilabel(app.SegmentationTab);
            app.ZPlaneSliderLabel.Text = 'Z Plane';
            app.ZPlaneSlider = uislider(app.SegmentationTab);
            app.ZPlaneSlider.Limits = [1 100];
            app.ZPlaneSlider.ValueChangingFcn = createCallbackFcn(app, @ZPlaneSliderValueChanging, true);
            app.ZPlaneSlider.Enable = 'off';
            app.ZPlaneSlider.Value = 1;
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.TrackFig = uiaxes(app.TrackingTab);
            title(app.TrackFig, '')
            xlabel(app.TrackFig, '')
            ylabel(app.TrackFig, '')
            app.TrackViewLabel = uilabel(app.TrackingTab);
            app.TrackViewLabel.Text = 'Track View';
            app.DropDown = uidropdown(app.TrackingTab);
            app.DropDown.Items = {'All tracks', 'Confident tracks', 'Single track', 'Perinuclear', 'Telenuclear'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'All tracks';
            app.TracklengththresholdLabel = uilabel(app.TrackingTab);
            app.TracklengththresholdLabel.Text = 'Track length threshold';
            app.keptTracksThresh = uispinner(app.TrackingTab);
            app.keptTracksThresh.Limits = [0 Inf];
            app.keptTracksThresh.ValueChangedFcn = createCallbackFcn(app, @keptTracksThreshValueChanged, true);
            app.keptTracksThresh.Enable = 'off';
            app.keptTracksThresh.Value = 1;
            app.TracktoviewLabel = uilabel(app.TrackingTab);
            app.TracktoviewLabel.Text = 'Track to view';
            app.singleTrack = uispinner(app.TrackingTab);
            app.singleTrack.Limits = [0 Inf];
            app.singleTrack.ValueChangedFcn = createCallbackFcn(app, @singleTrackValueChanged, true);
            app.singleTrack.Enable = 'off';
            app.singleTrack.Value = 1;
            app.ObjectCheckBox = uicheckbox(app.TrackingTab);
            app.ObjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @ObjectCheckBoxValueChanged, true);
            app.ObjectCheckBox.Text = 'Extrema';
            app.SavetrackstomatfilesPanel = uipanel(app.TrackingTab);
            app.SavetrackstomatfilesPanel.Title = 'Save tracks to .mat files';
            app.SavetrackstomatfilesPanel.Visible = 'off';
            app.SelectedAll = uibutton(app.SavetrackstomatfilesPanel, 'push');
            app.SelectedAll.ButtonPushedFcn = createCallbackFcn(app, @SelectedAllPushed, true);
            app.SelectedAll.Text = 'Selected';
            app.SelectedKept = uibutton(app.SavetrackstomatfilesPanel, 'push');
            app.SelectedKept.ButtonPushedFcn = createCallbackFcn(app, @SelectedKeptButtonPushed, true);
            app.SelectedKept.Text = 'Selected';
            app.SelectedPerf = uibutton(app.SavetrackstomatfilesPanel, 'push');
            app.SelectedPerf.ButtonPushedFcn = createCallbackFcn(app, @SelectedPerfButtonPushed, true);
            app.SelectedPerf.Text = 'Selected';
            app.AllPerf = uibutton(app.SavetrackstomatfilesPanel, 'push');
            app.AllPerf.ButtonPushedFcn = createCallbackFcn(app, @AllPerfButtonPushed, true);
            app.AllPerf.Text = 'All';
            app.AllKept = uibutton(app.SavetrackstomatfilesPanel, 'push');
            app.AllKept.ButtonPushedFcn = createCallbackFcn(app, @AllKeptButtonPushed, true);
            app.AllKept.Text = 'All';
            app.AllAll = uibutton(app.SavetrackstomatfilesPanel, 'push');
            app.AllAll.ButtonPushedFcn = createCallbackFcn(app, @AllAllPushed, true);
            app.AllAll.Text = 'All';
            app.AlltracksLabel = uilabel(app.SavetrackstomatfilesPanel);
            app.AlltracksLabel.Text = 'All tracks';
            app.KepttracksLabel = uilabel(app.SavetrackstomatfilesPanel);
            app.KepttracksLabel.Text = 'Kept tracks';
            app.PerfecttracksLabel = uilabel(app.SavetrackstomatfilesPanel);
            app.PerfecttracksLabel.Text = 'Perfect tracks';
            app.ZAspectRatioSpinnerLabel = uilabel(app.TrackingTab);
            app.ZAspectRatioSpinnerLabel.Text = 'Z Aspect Ratio';
            app.ZAspectRatioSpinner = uispinner(app.TrackingTab);
            app.ZAspectRatioSpinner.Limits = [1 Inf];
            app.ZAspectRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @ZAspectRatioSpinnerValueChanged, true);
            app.ZAspectRatioSpinner.Enable = 'off';
            app.ZAspectRatioSpinner.Value = 1;
            app.SelectedButton = uibutton(app.TrackingTab, 'push');
            app.SelectedButton.ButtonPushedFcn = createCallbackFcn(app, @SelectedButtonPushed, true);
            app.SelectedButton.Text = 'Selected';
            app.AllButton = uibutton(app.TrackingTab, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.SavetracksLabel = uilabel(app.TrackingTab);
            app.SavetracksLabel.Text = 'Save tracks';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxes = uiaxes(app.AnalysisTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ListBox = uilistbox(app.AnalysisTab);
            app.ListBox.Items = {'Area (μm^2) / Volume (μm^3)', 'Major Axis Length (μm)', 'Minor Axis Length (μm)', 'Solidity', 'Perimeter (μm) / Surface Area (μm)', 'Mean Intensity', 'Fission', 'Fusion', 'Distance (μm)', 'Displacement (μm)', 'Speed (μm/s)', 'Velocity (μm/s)', 'Z Axis Length'};
            app.ListBox.ItemsData = {'Area', 'MajorAxisLength', 'MinorAxisLength', 'Solidity', 'Perimeter', 'MeanIntensity', 'fission', 'fusion', 'distance', 'displacement', 'speed', 'velocity', 'ZAxisLength'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.CreategroupButton = uibutton(app.AnalysisTab, 'push');
            app.CreategroupButton.ButtonPushedFcn = createCallbackFcn(app, @CreategroupButtonPushed, true);
            app.CreategroupButton.Text = 'Create group';
            app.GroupsDropDownLabel = uilabel(app.AnalysisTab);
            app.GroupsDropDownLabel.Text = 'Groups';
            app.GroupsDropDown = uidropdown(app.AnalysisTab);
            app.GroupsDropDown.Items = {};
            app.GroupsDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupsDropDownValueChanged, true);
            app.GroupsDropDown.Enable = 'off';
            app.GroupsDropDown.Value = {};
            app.AddtographButton = uibutton(app.AnalysisTab, 'push');
            app.AddtographButton.ButtonPushedFcn = createCallbackFcn(app, @AddtographButtonPushed, true);
            app.AddtographButton.Enable = 'off';
            app.AddtographButton.Text = 'Add to graph';
            app.SavetotxtButton = uibutton(app.AnalysisTab, 'push');
            app.SavetotxtButton.ButtonPushedFcn = createCallbackFcn(app, @SavetotxtButtonPushed, true);
            app.SavetotxtButton.Enable = 'off';
            app.SavetotxtButton.Text = 'Save to txt';
            app.LogscaleCheckBox = uicheckbox(app.AnalysisTab);
            app.LogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBoxValueChanged, true);
            app.LogscaleCheckBox.Text = 'Log scale';
            app.ResetgraphButton = uibutton(app.AnalysisTab, 'push');
            app.ResetgraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResetgraphButtonPushed, true);
            app.ResetgraphButton.Text = 'Reset graph';
            app.GraphtypeButtonGroup = uibuttongroup(app.AnalysisTab);
            app.GraphtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphtypeButtonGroupSelectionChanged, true);
            app.GraphtypeButtonGroup.Title = 'Graph type';
            app.HistogramButton = uiradiobutton(app.GraphtypeButtonGroup);
            app.HistogramButton.Text = 'Histogram';
            app.HistogramButton.Value = true;
            app.MedianBarButton = uiradiobutton(app.GraphtypeButtonGroup);
            app.MedianBarButton.Text = 'Median Bar';
            app.pvalueEditFieldLabel = uilabel(app.AnalysisTab);
            app.pvalueEditFieldLabel.Text = 'p-value';
            app.pvalueEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.pvalueEditField.Editable = 'off';
            app.pvalueEditField.Value = 1;
            app.OnewayANOVALabel = uilabel(app.AnalysisTab);
            app.OnewayANOVALabel.Text = 'One-way ANOVA';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.SegmentationPanel = uipanel(app.AdvancedSettingsTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.MinimummitochondrialareaumEditFieldLabel = uilabel(app.SegmentationPanel);
            app.MinimummitochondrialareaumEditFieldLabel.Text = 'Minimum mitochondrial area (um)';
            app.customMinArea = uieditfield(app.SegmentationPanel, 'numeric');
            app.customMinArea.Limits = [0 Inf];
            app.customMinArea.ValueChangedFcn = createCallbackFcn(app, @customMinAreaValueChanged, true);
            app.customMinArea.Value = 0.3;
            app.MaximummitochondrialareaumEditFieldLabel = uilabel(app.SegmentationPanel);
            app.MaximummitochondrialareaumEditFieldLabel.Text = 'Maximum mitochondrial area (um)';
            app.customMaxArea = uieditfield(app.SegmentationPanel, 'numeric');
            app.customMaxArea.Limits = [0 Inf];
            app.customMaxArea.ValueChangedFcn = createCallbackFcn(app, @customMaxAreaValueChanged, true);
            app.customMaxArea.Value = 200;
            app.CustomgaussfiltsigmaEditFieldLabel = uilabel(app.SegmentationPanel);
            app.CustomgaussfiltsigmaEditFieldLabel.Text = 'Custom gauss filt sigma';
            app.customSigVal = uieditfield(app.SegmentationPanel, 'numeric');
            app.customSigVal.Limits = [0 Inf];
            app.customSigVal.ValueChangedFcn = createCallbackFcn(app, @customSigValValueChanged, true);
            app.customSigVal.Enable = 'off';
            app.gaussCheckbox = uicheckbox(app.SegmentationPanel);
            app.gaussCheckbox.ValueChangedFcn = createCallbackFcn(app, @gaussCheckboxValueChanged, true);
            app.gaussCheckbox.Text = '';
            app.CustomthresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.CustomthresholdEditFieldLabel.Text = 'Custom threshold';
            app.customThrVal = uieditfield(app.SegmentationPanel, 'numeric');
            app.customThrVal.Limits = [0 Inf];
            app.customThrVal.ValueChangedFcn = createCallbackFcn(app, @customThrValValueChanged, true);
            app.customThrVal.Enable = 'off';
            app.threshCheckbox = uicheckbox(app.SegmentationPanel);
            app.threshCheckbox.ValueChangedFcn = createCallbackFcn(app, @threshCheckboxValueChanged, true);
            app.threshCheckbox.Text = '';
            app.TrackingPanel = uipanel(app.AdvancedSettingsTab);
            app.TrackingPanel.Title = 'Tracking';
            app.MaxvelocitythresholdmsEditFieldLabel = uilabel(app.TrackingPanel);
            app.MaxvelocitythresholdmsEditFieldLabel.Text = 'Max velocity threshold (μm/s)';
            app.customDistThresh = uieditfield(app.TrackingPanel, 'numeric');
            app.customDistThresh.Limits = [0 Inf];
            app.customDistThresh.ValueChangedFcn = createCallbackFcn(app, @customDistThreshValueChanged, true);
            app.customDistThresh.Value = 1;
            app.MaxwaittimethresholdsEditFieldLabel = uilabel(app.TrackingPanel);
            app.MaxwaittimethresholdsEditFieldLabel.Text = 'Max wait time threshold (s)';
            app.customFrameThresh = uieditfield(app.TrackingPanel, 'numeric');
            app.customFrameThresh.Limits = [0 Inf];
            app.customFrameThresh.ValueChangedFcn = createCallbackFcn(app, @customFrameThreshValueChanged, true);
            app.customFrameThresh.Value = Inf;
            app.fisFusCheckbox = uicheckbox(app.TrackingPanel);
            app.fisFusCheckbox.ValueChangedFcn = createCallbackFcn(app, @fisFusCheckboxValueChanged, true);
            app.fisFusCheckbox.Text = 'Skip fission and fusion';
            app.CustomweightsLabel = uilabel(app.TrackingPanel);
            app.CustomweightsLabel.Text = 'Custom weights';
            app.DistanceEditFieldLabel = uilabel(app.TrackingPanel);
            app.DistanceEditFieldLabel.Visible = 'off';
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.customDistWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customDistWeight.Limits = [0 Inf];
            app.customDistWeight.ValueChangedFcn = createCallbackFcn(app, @customDistWeightValueChanged, true);
            app.customDistWeight.Enable = 'off';
            app.customDistWeight.Visible = 'off';
            app.AreaVolumeEditFieldLabel = uilabel(app.TrackingPanel);
            app.AreaVolumeEditFieldLabel.Text = 'Area/Volume';
            app.customAreaWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customAreaWeight.Limits = [0 Inf];
            app.customAreaWeight.ValueChangedFcn = createCallbackFcn(app, @customAreaWeightValueChanged, true);
            app.customAreaWeight.Enable = 'off';
            app.MajorAxisEditFieldLabel = uilabel(app.TrackingPanel);
            app.MajorAxisEditFieldLabel.Text = 'Major Axis';
            app.customMajAxWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customMajAxWeight.Limits = [0 Inf];
            app.customMajAxWeight.ValueChangedFcn = createCallbackFcn(app, @customMajAxWeightValueChanged, true);
            app.customMajAxWeight.Enable = 'off';
            app.MinorAxisEditFieldLabel = uilabel(app.TrackingPanel);
            app.MinorAxisEditFieldLabel.Text = 'Minor Axis';
            app.customMinAxWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customMinAxWeight.Limits = [0 Inf];
            app.customMinAxWeight.ValueChangedFcn = createCallbackFcn(app, @customMinAxWeightValueChanged, true);
            app.customMinAxWeight.Enable = 'off';
            app.SolidityEditFieldLabel = uilabel(app.TrackingPanel);
            app.SolidityEditFieldLabel.Text = 'Solidity';
            app.customSolWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customSolWeight.Limits = [0 Inf];
            app.customSolWeight.ValueChangedFcn = createCallbackFcn(app, @customSolWeightValueChanged, true);
            app.customSolWeight.Enable = 'off';
            app.PerimeterSurfaceAreaEditFieldLabel = uilabel(app.TrackingPanel);
            app.PerimeterSurfaceAreaEditFieldLabel.Text = 'Perimeter/Surface Area';
            app.customPeriWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customPeriWeight.Limits = [0 Inf];
            app.customPeriWeight.ValueChangedFcn = createCallbackFcn(app, @customPeriWeightValueChanged, true);
            app.customPeriWeight.Enable = 'off';
            app.IntensityEditFieldLabel = uilabel(app.TrackingPanel);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.customIntWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customIntWeight.Limits = [0 Inf];
            app.customIntWeight.ValueChangedFcn = createCallbackFcn(app, @customIntWeightValueChanged, true);
            app.customIntWeight.Enable = 'off';
            app.ZAxis3DonlyEditFieldLabel = uilabel(app.TrackingPanel);
            app.ZAxis3DonlyEditFieldLabel.Text = 'Z Axis (3D only)';
            app.customZAxWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customZAxWeight.Limits = [0 Inf];
            app.customZAxWeight.ValueChangedFcn = createCallbackFcn(app, @customZAxWeightValueChanged, true);
            app.customZAxWeight.Enable = 'off';
            app.customWeightCheckbox = uicheckbox(app.TrackingPanel);
            app.customWeightCheckbox.ValueChangedFcn = createCallbackFcn(app, @customWeightCheckboxValueChanged, true);
            app.customWeightCheckbox.Text = '';
            app.LoadingLabel = uilabel(app.MitometerUIFigure);
            app.LoadingLabel.Visible = 'off';
            app.LoadingLabel.Text = 'Loading...';
            app.ImageList = uilistbox(app.MitometerUIFigure);
            app.ImageList.Items = {};
            app.ImageList.Multiselect = 'on';
            app.ImageList.ValueChangedFcn = createCallbackFcn(app, @ImageListValueChanged, true);
            app.ImageList.Value = {};
            app.SelectfilesLabel = uilabel(app.MitometerUIFigure);
            app.SelectfilesLabel.Text = 'Select file(s)';
            app.SelecthighlightedButton = uibutton(app.MitometerUIFigure, 'push');
            app.SelecthighlightedButton.ButtonPushedFcn = createCallbackFcn(app, @SelecthighlightedButtonPushed, true);
            app.SelecthighlightedButton.Text = 'Select highlighted';
            % Show the figure after all components are created
            app.MitometerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MitometerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MitometerUIFigure)
        end
    end
end