classdef groupingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GroupUIFigure               matlab.ui.Figure
        CreategroupButton           matlab.ui.control.Button
        DropDown                    matlab.ui.control.DropDown
        MintracklengthSpinnerLabel  matlab.ui.control.Label
        MintracklengthSpinner       matlab.ui.control.Spinner
        EditField                   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, trackLength, trackType)
            app.CallingApp = mainapp;
            app.MintracklengthSpinner.Value = trackLength;
            app.DropDown.Value = trackType;
        end

        % Button pushed function: CreategroupButton
        function CreategroupButtonPushed(app, event)

            groupingParams(app.CallingApp,app.MintracklengthSpinner.Value,app.DropDown.Value,app.EditField.Value)
            
            GroupUIFigureCloseRequest(app,event)
        end

        % Close request function: GroupUIFigure
        function GroupUIFigureCloseRequest(app, event)
            app.CallingApp.CreategroupButton.Enable = true;
            
            delete(app)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GroupUIFigure = uifigure('Visible', 'off');
            app.GroupUIFigure.Name = 'Group';
            app.GroupUIFigure.CloseRequestFcn = createCallbackFcn(app, @GroupUIFigureCloseRequest, true);
            app.CreategroupButton = uibutton(app.GroupUIFigure, 'push');
            app.CreategroupButton.ButtonPushedFcn = createCallbackFcn(app, @CreategroupButtonPushed, true);
            app.CreategroupButton.Text = 'Create group';
            app.DropDown = uidropdown(app.GroupUIFigure);
            app.DropDown.Items = {'All tracks', 'Perfect only', 'Perinuclear', 'Telenuclear'};
            app.DropDown.ItemsData = {'1', '2', '3', '4'};
            app.DropDown.Value = '1';
            app.MintracklengthSpinnerLabel = uilabel(app.GroupUIFigure);
            app.MintracklengthSpinnerLabel.Text = 'Min track length';
            app.MintracklengthSpinner = uispinner(app.GroupUIFigure);
            app.MintracklengthSpinner.Limits = [0 Inf];
            app.MintracklengthSpinner.RoundFractionalValues = 'on';
            app.MintracklengthSpinner.Value = 1;
            app.EditField = uieditfield(app.GroupUIFigure, 'text');
            app.EditField.Value = 'name';
            % Show the figure after all components are created
            app.GroupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = groupingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GroupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GroupUIFigure)
        end
    end
end